classdef comp1 < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        Button_65      matlab.ui.control.StateButton
        Button_64      matlab.ui.control.StateButton
        TextArea       matlab.ui.control.TextArea
        TextAreaLabel  matlab.ui.control.Label
        Panel          matlab.ui.container.Panel
        Button_62      matlab.ui.control.StateButton
        Slider_2       matlab.ui.control.Slider
        Slider_2Label  matlab.ui.control.Label
        Button_63      matlab.ui.control.StateButton
        Switch         matlab.ui.control.Switch
        Label_2        matlab.ui.control.Label
        Slider         matlab.ui.control.Slider
        Label          matlab.ui.control.Label
        DropDown       matlab.ui.control.DropDown
        Button_61      matlab.ui.control.StateButton
        Button_60      matlab.ui.control.StateButton
        Button_59      matlab.ui.control.StateButton
        Button_58      matlab.ui.control.StateButton
        Button_57      matlab.ui.control.StateButton
        Button_56      matlab.ui.control.StateButton
        Button_55      matlab.ui.control.StateButton
        Button_54      matlab.ui.control.StateButton
        Button_53      matlab.ui.control.StateButton
        Button_52      matlab.ui.control.StateButton
        Button_51      matlab.ui.control.StateButton
        Button_50      matlab.ui.control.StateButton
        Button_49      matlab.ui.control.StateButton
        Button_48      matlab.ui.control.StateButton
        Button_47      matlab.ui.control.StateButton
        Button_46      matlab.ui.control.StateButton
        Button_45      matlab.ui.control.StateButton
        Button_44      matlab.ui.control.StateButton
        Button_43      matlab.ui.control.StateButton
        Button_42      matlab.ui.control.StateButton
        Button_41      matlab.ui.control.StateButton
        Button_40      matlab.ui.control.StateButton
        Button_39      matlab.ui.control.StateButton
        Button_38      matlab.ui.control.StateButton
        Button_37      matlab.ui.control.StateButton
        Button_36      matlab.ui.control.StateButton
        Button_35      matlab.ui.control.StateButton
        Button_34      matlab.ui.control.StateButton
        Button_33      matlab.ui.control.StateButton
        Button_31      matlab.ui.control.StateButton
        Button_30      matlab.ui.control.StateButton
        Button_29      matlab.ui.control.StateButton
        Button_28      matlab.ui.control.StateButton
        Button_27      matlab.ui.control.StateButton
        Button_25      matlab.ui.control.StateButton
        Button_24      matlab.ui.control.StateButton
        Button_23      matlab.ui.control.StateButton
        Button_22      matlab.ui.control.StateButton
        Button_21      matlab.ui.control.StateButton
        Button_20      matlab.ui.control.StateButton
        Button_19      matlab.ui.control.StateButton
        Button_18      matlab.ui.control.StateButton
        Button_17      matlab.ui.control.StateButton
        Button_16      matlab.ui.control.StateButton
        Button_15      matlab.ui.control.StateButton
        Button_14      matlab.ui.control.StateButton
        Button_13      matlab.ui.control.StateButton
        Button_12      matlab.ui.control.StateButton
        Button_11      matlab.ui.control.StateButton
        Button_10      matlab.ui.control.StateButton
        Button_9       matlab.ui.control.StateButton
        Button_8       matlab.ui.control.StateButton
        Button_6       matlab.ui.control.StateButton
        Button_5       matlab.ui.control.StateButton
        Button_4       matlab.ui.control.StateButton
        Button_3       matlab.ui.control.StateButton
        Button_2       matlab.ui.control.StateButton
        Button         matlab.ui.control.StateButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Button_62
        function fc(comp, event)

        end

        % Value changed function: Button_60
        function si___(comp, event)

        end

        % Callback function: not associated with a component
        function Button_62ValueChanged(comp, event)
            value = comp.Button_62.Value;
            if value == 1
                comp.Button_62.BackgroundColor = 'blue'; % 更改按钮颜色为蓝色
                pause(0.25); % 延迟0.5秒
                comp.Button_62.Value=0;
                comp.Button_62.BackgroundColor = [0.94,0.94,0.94]; % 恢复按钮原始颜色
            end
        end

        % Value changed function: Button_64
        function Button_64ValueChanged(comp, event)
            value = comp.Button_64.Value;
            if value == 1
                comp.Button_64.BackgroundColor = 'yellow'; % 更改按钮颜色为蓝色
                pause(0.25); % 延迟0.5秒
                comp.Button_64.Value=0;
                comp.Button_64.BackgroundColor = [0.94,0.94,0.94]; % 恢复按钮原始颜色
            end
        end
    end

    methods (Access = protected)
        
        % 公共属性值发生更改时执行的代码
        function update(comp)
            % 使用此函数更新底层组件
            
        end

        % 创建底层组件
        function setup(comp)

            comp.Position = [1 1 952 452];
            comp.BackgroundColor = [0.94 0.94 0.94];

            % Create Button
            comp.Button = uibutton(comp, 'state');
            comp.Button.Text = '';
            comp.Button.BackgroundColor = [1 1 1];
            comp.Button.Position = [16 245 20 141];

            % Create Button_2
            comp.Button_2 = uibutton(comp, 'state');
            comp.Button_2.Text = '';
            comp.Button_2.BackgroundColor = [1 1 1];
            comp.Button_2.Position = [37 245 20 141];

            % Create Button_3
            comp.Button_3 = uibutton(comp, 'state');
            comp.Button_3.Text = '';
            comp.Button_3.BackgroundColor = [1 1 1];
            comp.Button_3.Position = [57 245 20 141];

            % Create Button_4
            comp.Button_4 = uibutton(comp, 'state');
            comp.Button_4.Text = '';
            comp.Button_4.BackgroundColor = [1 1 1];
            comp.Button_4.Position = [77 245 20 141];

            % Create Button_5
            comp.Button_5 = uibutton(comp, 'state');
            comp.Button_5.Text = '';
            comp.Button_5.BackgroundColor = [1 1 1];
            comp.Button_5.Position = [97 245 20 141];

            % Create Button_6
            comp.Button_6 = uibutton(comp, 'state');
            comp.Button_6.Text = '';
            comp.Button_6.BackgroundColor = [1 1 1];
            comp.Button_6.Position = [117 245 20 141];

            % Create Button_8
            comp.Button_8 = uibutton(comp, 'state');
            comp.Button_8.Text = '';
            comp.Button_8.BackgroundColor = [0.149 0.149 0.149];
            comp.Button_8.Position = [31 309 10 77];
            comp.Button_8.Value = true;

            % Create Button_9
            comp.Button_9 = uibutton(comp, 'state');
            comp.Button_9.Text = '';
            comp.Button_9.BackgroundColor = [0.149 0.149 0.149];
            comp.Button_9.Position = [51 309 10 77];
            comp.Button_9.Value = true;

            % Create Button_10
            comp.Button_10 = uibutton(comp, 'state');
            comp.Button_10.Text = '';
            comp.Button_10.BackgroundColor = [0.149 0.149 0.149];
            comp.Button_10.Position = [91 309 10 77];
            comp.Button_10.Value = true;

            % Create Button_11
            comp.Button_11 = uibutton(comp, 'state');
            comp.Button_11.Text = '';
            comp.Button_11.BackgroundColor = [0.149 0.149 0.149];
            comp.Button_11.Position = [111 309 10 77];
            comp.Button_11.Value = true;

            % Create Button_12
            comp.Button_12 = uibutton(comp, 'state');
            comp.Button_12.Text = '';
            comp.Button_12.BackgroundColor = [1 1 1];
            comp.Button_12.Position = [137 245 20 141];

            % Create Button_13
            comp.Button_13 = uibutton(comp, 'state');
            comp.Button_13.Text = '';
            comp.Button_13.BackgroundColor = [0.149 0.149 0.149];
            comp.Button_13.Position = [131 309 10 77];
            comp.Button_13.Value = true;

            % Create Button_14
            comp.Button_14 = uibutton(comp, 'state');
            comp.Button_14.Text = '';
            comp.Button_14.BackgroundColor = [1 1 1];
            comp.Button_14.Position = [157 245 20 141];

            % Create Button_15
            comp.Button_15 = uibutton(comp, 'state');
            comp.Button_15.Text = '';
            comp.Button_15.BackgroundColor = [1 1 1];
            comp.Button_15.Position = [178 245 20 141];

            % Create Button_16
            comp.Button_16 = uibutton(comp, 'state');
            comp.Button_16.Text = '';
            comp.Button_16.BackgroundColor = [1 1 1];
            comp.Button_16.Position = [198 245 20 141];

            % Create Button_17
            comp.Button_17 = uibutton(comp, 'state');
            comp.Button_17.Text = '';
            comp.Button_17.BackgroundColor = [1 1 1];
            comp.Button_17.Position = [218 245 20 141];

            % Create Button_18
            comp.Button_18 = uibutton(comp, 'state');
            comp.Button_18.Text = '';
            comp.Button_18.BackgroundColor = [1 1 1];
            comp.Button_18.Position = [238 245 20 141];

            % Create Button_19
            comp.Button_19 = uibutton(comp, 'state');
            comp.Button_19.Text = '';
            comp.Button_19.BackgroundColor = [1 1 1];
            comp.Button_19.Position = [258 245 20 141];

            % Create Button_20
            comp.Button_20 = uibutton(comp, 'state');
            comp.Button_20.Text = '';
            comp.Button_20.BackgroundColor = [0.149 0.149 0.149];
            comp.Button_20.Position = [172 309 10 77];
            comp.Button_20.Value = true;

            % Create Button_21
            comp.Button_21 = uibutton(comp, 'state');
            comp.Button_21.Text = '';
            comp.Button_21.BackgroundColor = [0.149 0.149 0.149];
            comp.Button_21.Position = [192 309 10 77];
            comp.Button_21.Value = true;

            % Create Button_22
            comp.Button_22 = uibutton(comp, 'state');
            comp.Button_22.Text = '';
            comp.Button_22.BackgroundColor = [0.149 0.149 0.149];
            comp.Button_22.Position = [232 309 10 77];
            comp.Button_22.Value = true;

            % Create Button_23
            comp.Button_23 = uibutton(comp, 'state');
            comp.Button_23.Text = '';
            comp.Button_23.BackgroundColor = [0.149 0.149 0.149];
            comp.Button_23.Position = [252 309 10 77];
            comp.Button_23.Value = true;

            % Create Button_24
            comp.Button_24 = uibutton(comp, 'state');
            comp.Button_24.Text = '';
            comp.Button_24.BackgroundColor = [1 1 1];
            comp.Button_24.Position = [278 245 20 141];

            % Create Button_25
            comp.Button_25 = uibutton(comp, 'state');
            comp.Button_25.Text = '';
            comp.Button_25.BackgroundColor = [0.149 0.149 0.149];
            comp.Button_25.Position = [272 309 10 77];
            comp.Button_25.Value = true;

            % Create Button_27
            comp.Button_27 = uibutton(comp, 'state');
            comp.Button_27.Text = '';
            comp.Button_27.BackgroundColor = [1 1 1];
            comp.Button_27.Position = [318 245 20 141];

            % Create Button_28
            comp.Button_28 = uibutton(comp, 'state');
            comp.Button_28.Text = '';
            comp.Button_28.BackgroundColor = [1 1 1];
            comp.Button_28.Position = [338 245 20 141];

            % Create Button_29
            comp.Button_29 = uibutton(comp, 'state');
            comp.Button_29.Text = '';
            comp.Button_29.BackgroundColor = [1 1 1];
            comp.Button_29.Position = [358 245 20 141];

            % Create Button_30
            comp.Button_30 = uibutton(comp, 'state');
            comp.Button_30.Text = '';
            comp.Button_30.BackgroundColor = [1 1 1];
            comp.Button_30.Position = [378 245 20 141];

            % Create Button_31
            comp.Button_31 = uibutton(comp, 'state');
            comp.Button_31.Text = '';
            comp.Button_31.BackgroundColor = [1 1 1];
            comp.Button_31.Position = [398 245 20 141];

            % Create Button_33
            comp.Button_33 = uibutton(comp, 'state');
            comp.Button_33.Text = '';
            comp.Button_33.BackgroundColor = [0.149 0.149 0.149];
            comp.Button_33.Position = [332 309 10 77];
            comp.Button_33.Value = true;

            % Create Button_34
            comp.Button_34 = uibutton(comp, 'state');
            comp.Button_34.Text = '';
            comp.Button_34.BackgroundColor = [0.149 0.149 0.149];
            comp.Button_34.Position = [372 309 10 77];
            comp.Button_34.Value = true;

            % Create Button_35
            comp.Button_35 = uibutton(comp, 'state');
            comp.Button_35.Text = '';
            comp.Button_35.BackgroundColor = [0.149 0.149 0.149];
            comp.Button_35.Position = [392 309 10 77];
            comp.Button_35.Value = true;

            % Create Button_36
            comp.Button_36 = uibutton(comp, 'state');
            comp.Button_36.Text = '';
            comp.Button_36.BackgroundColor = [1 1 1];
            comp.Button_36.Position = [418 245 20 141];

            % Create Button_37
            comp.Button_37 = uibutton(comp, 'state');
            comp.Button_37.Text = '';
            comp.Button_37.BackgroundColor = [0.149 0.149 0.149];
            comp.Button_37.Position = [412 309 10 77];
            comp.Button_37.Value = true;

            % Create Button_38
            comp.Button_38 = uibutton(comp, 'state');
            comp.Button_38.Text = '';
            comp.Button_38.BackgroundColor = [1 1 1];
            comp.Button_38.Position = [439 245 20 141];

            % Create Button_39
            comp.Button_39 = uibutton(comp, 'state');
            comp.Button_39.Text = '';
            comp.Button_39.BackgroundColor = [1 1 1];
            comp.Button_39.Position = [460 245 20 141];

            % Create Button_40
            comp.Button_40 = uibutton(comp, 'state');
            comp.Button_40.Text = '';
            comp.Button_40.BackgroundColor = [1 1 1];
            comp.Button_40.Position = [480 245 20 141];

            % Create Button_41
            comp.Button_41 = uibutton(comp, 'state');
            comp.Button_41.Text = '';
            comp.Button_41.BackgroundColor = [1 1 1];
            comp.Button_41.Position = [500 245 20 141];

            % Create Button_42
            comp.Button_42 = uibutton(comp, 'state');
            comp.Button_42.Text = '';
            comp.Button_42.BackgroundColor = [1 1 1];
            comp.Button_42.Position = [520 245 20 141];

            % Create Button_43
            comp.Button_43 = uibutton(comp, 'state');
            comp.Button_43.Text = '';
            comp.Button_43.BackgroundColor = [1 1 1];
            comp.Button_43.Position = [540 245 20 141];

            % Create Button_44
            comp.Button_44 = uibutton(comp, 'state');
            comp.Button_44.Text = '';
            comp.Button_44.BackgroundColor = [0.149 0.149 0.149];
            comp.Button_44.Position = [454 309 10 77];
            comp.Button_44.Value = true;

            % Create Button_45
            comp.Button_45 = uibutton(comp, 'state');
            comp.Button_45.Text = '';
            comp.Button_45.BackgroundColor = [0.149 0.149 0.149];
            comp.Button_45.Position = [474 309 10 77];
            comp.Button_45.Value = true;

            % Create Button_46
            comp.Button_46 = uibutton(comp, 'state');
            comp.Button_46.Text = '';
            comp.Button_46.BackgroundColor = [0.149 0.149 0.149];
            comp.Button_46.Position = [514 309 10 77];
            comp.Button_46.Value = true;

            % Create Button_47
            comp.Button_47 = uibutton(comp, 'state');
            comp.Button_47.Text = '';
            comp.Button_47.BackgroundColor = [0.149 0.149 0.149];
            comp.Button_47.Position = [534 309 10 77];
            comp.Button_47.Value = true;

            % Create Button_48
            comp.Button_48 = uibutton(comp, 'state');
            comp.Button_48.Text = '';
            comp.Button_48.BackgroundColor = [1 1 1];
            comp.Button_48.Position = [560 245 20 141];

            % Create Button_49
            comp.Button_49 = uibutton(comp, 'state');
            comp.Button_49.Text = '';
            comp.Button_49.BackgroundColor = [0.149 0.149 0.149];
            comp.Button_49.Position = [554 309 10 77];
            comp.Button_49.Value = true;

            % Create Button_50
            comp.Button_50 = uibutton(comp, 'state');
            comp.Button_50.Text = '';
            comp.Button_50.BackgroundColor = [1 1 1];
            comp.Button_50.Position = [580 245 20 141];

            % Create Button_51
            comp.Button_51 = uibutton(comp, 'state');
            comp.Button_51.Text = '';
            comp.Button_51.BackgroundColor = [1 1 1];
            comp.Button_51.Position = [601 245 20 141];

            % Create Button_52
            comp.Button_52 = uibutton(comp, 'state');
            comp.Button_52.Text = '';
            comp.Button_52.BackgroundColor = [1 1 1];
            comp.Button_52.Position = [621 245 20 141];

            % Create Button_53
            comp.Button_53 = uibutton(comp, 'state');
            comp.Button_53.Text = '';
            comp.Button_53.BackgroundColor = [1 1 1];
            comp.Button_53.Position = [641 245 20 141];

            % Create Button_54
            comp.Button_54 = uibutton(comp, 'state');
            comp.Button_54.Text = '';
            comp.Button_54.BackgroundColor = [1 1 1];
            comp.Button_54.Position = [661 245 20 141];

            % Create Button_55
            comp.Button_55 = uibutton(comp, 'state');
            comp.Button_55.Text = '';
            comp.Button_55.BackgroundColor = [1 1 1];
            comp.Button_55.Position = [681 245 20 141];

            % Create Button_56
            comp.Button_56 = uibutton(comp, 'state');
            comp.Button_56.Text = '';
            comp.Button_56.BackgroundColor = [0.149 0.149 0.149];
            comp.Button_56.Position = [595 309 10 77];
            comp.Button_56.Value = true;

            % Create Button_57
            comp.Button_57 = uibutton(comp, 'state');
            comp.Button_57.Text = '';
            comp.Button_57.BackgroundColor = [0.149 0.149 0.149];
            comp.Button_57.Position = [615 309 10 77];
            comp.Button_57.Value = true;

            % Create Button_58
            comp.Button_58 = uibutton(comp, 'state');
            comp.Button_58.Text = '';
            comp.Button_58.BackgroundColor = [0.149 0.149 0.149];
            comp.Button_58.Position = [655 309 10 77];
            comp.Button_58.Value = true;

            % Create Button_59
            comp.Button_59 = uibutton(comp, 'state');
            comp.Button_59.Text = '';
            comp.Button_59.BackgroundColor = [0.149 0.149 0.149];
            comp.Button_59.Position = [675 309 10 77];
            comp.Button_59.Value = true;

            % Create Button_60
            comp.Button_60 = uibutton(comp, 'state');
            comp.Button_60.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @si___, true);
            comp.Button_60.Text = '';
            comp.Button_60.BackgroundColor = [1 1 1];
            comp.Button_60.Position = [701 245 20 141];

            % Create Button_61
            comp.Button_61 = uibutton(comp, 'state');
            comp.Button_61.Text = '';
            comp.Button_61.BackgroundColor = [0.149 0.149 0.149];
            comp.Button_61.Position = [695 309 10 77];
            comp.Button_61.Value = true;

            % Create DropDown
            comp.DropDown = uidropdown(comp);
            comp.DropDown.Items = {'C大调', 'D大调', 'E大调', 'F大调', 'A大调', 'B大调'};
            comp.DropDown.Position = [19 410 87 22];
            comp.DropDown.Value = 'C大调';

            % Create Label
            comp.Label = uilabel(comp);
            comp.Label.HorizontalAlignment = 'right';
            comp.Label.Position = [137 201 29 22];
            comp.Label.Text = '音量';

            % Create Slider
            comp.Slider = uislider(comp);
            comp.Slider.MajorTicks = [0 20 40 60 80 100];
            comp.Slider.Position = [189 210 287 3];

            % Create Label_2
            comp.Label_2 = uilabel(comp);
            comp.Label_2.HorizontalAlignment = 'center';
            comp.Label_2.Position = [17 201 29 22];
            comp.Label_2.Text = '延音';

            % Create Switch
            comp.Switch = uiswitch(comp, 'slider');
            comp.Switch.Position = [72 200 45 20];

            % Create Panel
            comp.Panel = uipanel(comp);
            comp.Panel.ForegroundColor = [0.0745 0.6235 1];
            comp.Panel.Title = 'Panel';
            comp.Panel.BackgroundColor = [0.8 0.8 0.8];
            comp.Panel.Position = [495 103 225 128];

            % Create Button_63
            comp.Button_63 = uibutton(comp.Panel, 'state');
            comp.Button_63.Text = '朗读';
            comp.Button_63.Position = [31 9 20 23];

            % Create Slider_2Label
            comp.Slider_2Label = uilabel(comp.Panel);
            comp.Slider_2Label.HorizontalAlignment = 'right';
            comp.Slider_2Label.Position = [-1 53 36 22];
            comp.Slider_2Label.Text = 'Slider';

            % Create Slider_2
            comp.Slider_2 = uislider(comp.Panel);
            comp.Slider_2.Position = [56 62 99 3];

            % Create Button_62
            comp.Button_62 = uibutton(comp.Panel, 'state');
            comp.Button_62.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @fc, true);
            comp.Button_62.Text = '导入音乐';
            comp.Button_62.Position = [127 9 44 23];

            % Create TextAreaLabel
            comp.TextAreaLabel = uilabel(comp);
            comp.TextAreaLabel.HorizontalAlignment = 'right';
            comp.TextAreaLabel.Position = [508 180 55 22];
            comp.TextAreaLabel.Text = 'Text Area';

            % Create TextArea
            comp.TextArea = uitextarea(comp);
            comp.TextArea.Position = [578 183 120 19];

            % Create Button_64
            comp.Button_64 = uibutton(comp, 'state');
            comp.Button_64.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Button_64ValueChanged, true);
            comp.Button_64.Text = '';
            comp.Button_64.BackgroundColor = [1 1 1];
            comp.Button_64.Position = [299 245 20 141];

            % Create Button_65
            comp.Button_65 = uibutton(comp, 'state');
            comp.Button_65.Text = '';
            comp.Button_65.BackgroundColor = [0.149 0.149 0.149];
            comp.Button_65.Position = [314 309 10 77];
            comp.Button_65.Value = true;
        end
    end
end